classdef FlockingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Window                     matlab.ui.Figure
        ArenaPlot                  matlab.ui.control.UIAxes
        KEditFieldLabel            matlab.ui.control.Label
        KEditField                 matlab.ui.control.NumericEditField
        BetaEditFieldLabel         matlab.ui.control.Label
        BetaEditField              matlab.ui.control.NumericEditField
        SigmaLabel                 matlab.ui.control.Label
        SigmaEditField             matlab.ui.control.NumericEditField
        LeaderTrajectoryLabel      matlab.ui.control.Label
        xtLabel                    matlab.ui.control.Label
        XTrajectoryEditField       matlab.ui.control.EditField
        ytLabel                    matlab.ui.control.Label
        YTrajectoryEditField       matlab.ui.control.EditField
        NumberofAgentsLabel        matlab.ui.control.Label
        NumAgentsEditField         matlab.ui.control.NumericEditField
        DurationLabel              matlab.ui.control.Label
        DurationEditField          matlab.ui.control.NumericEditField
        AgentDataTable             matlab.ui.control.Table
        StartButton                matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        CurrentTimeEditFieldLabel  matlab.ui.control.Label
        CurrentTimeEditField       matlab.ui.control.NumericEditField
        dtEditFieldLabel           matlab.ui.control.Label
        dtEditField                matlab.ui.control.NumericEditField
        SimulationControlsLabel    matlab.ui.control.Label
        AgentConfigurationLabel    matlab.ui.control.Label
        FilenameEditFieldLabel     matlab.ui.control.Label
        FilenameEditField          matlab.ui.control.EditField
        LoadButton                 matlab.ui.control.Button
        AgentInformationLabel      matlab.ui.control.Label
        PlotsCheckBox              matlab.ui.control.CheckBox
    end

    properties (Access = private)
        isStopPushed
    end
    
    methods (Access = private)
        
        function saveSimulationData(~, agentPositionHistory, agentVelocityHistory, f, dt)
            ssName = 'agentData.xlsx';
            matName = 'agentData.mat';
            if isfile(ssName)
                delete(ssName);
            end
            if isfile(matName)
                delete(matName);
            end
            % Trim history to final iteration
            agentPositionHistory = agentPositionHistory(1:f,:);
            agentVelocityHistory = agentVelocityHistory(1:f,:);
            save(matName,'agentPositionHistory','agentVelocityHistory');
            
            % Generate column headings for table
            N = (1:(size(agentPositionHistory,2)/2))';
            posHeadings = reshape(cat(2,cellstr(num2str(N,'x%d')),cellstr(num2str(N,'y%d')))',1,[]);
            velHeadings = reshape(cat(2,cellstr(num2str(N,'Vx%d')),cellstr(num2str(N,'Vy%d')))',1,[]);
            
            % Timestamp for rows
            timeStamp = dt*(0:f-1)';
            posHeadings = cat(2,cellstr('t'),posHeadings);
            velHeadings = cat(2,cellstr('t'),velHeadings);
            % Add timestamps to data
            agentPositionHistory = cat(2,timeStamp,agentPositionHistory);
            agentVelocityHistory = cat(2,timeStamp,agentVelocityHistory);
            
            % Generate tables
            agentPositionHistory = array2table(agentPositionHistory, 'VariableNames',posHeadings);
            agentVelocityHistory = array2table(agentVelocityHistory, 'VariableNames',velHeadings);
            
            writetable(agentPositionHistory,ssName,'FileType','spreadsheet','Sheet','Agent Position History');
            writetable(agentVelocityHistory,ssName,'FileType','spreadsheet','Sheet','Agent Velocity History');
        end
        
        function plotArena(app, agentPositionHistory, iteration)
            % Plot Label Spacer
            delta = 1/20;
            numAgents = size(agentPositionHistory,2)/2;
            % Trim agentPositionHistory to the current iteration
            agentPositionHistory = agentPositionHistory(1:iteration,:);
            
            app.ArenaPlot.ColorOrderIndex = 1;
            cla(app.ArenaPlot);
            hold(app.ArenaPlot, 'on');
            % Points
            plot(app.ArenaPlot, agentPositionHistory(:,1:2:end),...
                agentPositionHistory(:,2:2:end),'-o',"MarkerIndices",iteration-1);    
            % Labels
            text(app.ArenaPlot, agentPositionHistory(end,1:2:end)+delta,...
                agentPositionHistory(end,2:2:end)+delta,...
                cellfun(@num2str,num2cell((1:numAgents)'),'UniformOutput',false));
            hold(app.ArenaPlot, 'off')
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AgentDataTable.Data = zeros(app.NumAgentsEditField.Value,4);
            % Suppress warnings for spreadsheet writing
            warning('off','MATLAB:xlswrite:AddSheet');
        end

        % Value changed function: NumAgentsEditField
        function numAgentsChanged(app, event)
            % Change AgentDataTable size to reflect edit field change
            % New total number of agents 
            newNumAgents = app.NumAgentsEditField.Value; 
            % Old total number of agents
            oldNumAgents = size(app.AgentDataTable.Data,1);
            delta = newNumAgents - oldNumAgents;
            
            % If adding agents
            if delta>0
                app.AgentDataTable.Data = vertcat(app.AgentDataTable.Data, zeros(delta,4));
            % If removing agents
            else
                app.AgentDataTable.Data = app.AgentDataTable.Data(1:newNumAgents,:);
            end
        end

        % Button pushed function: StartButton
        function startSimulation(app, event)
            % Main simulation loop
            app.isStopPushed = false;
            
            %% User Variable Initialization %%
            time = 0;
            duration = app.DurationEditField.Value;
            shouldPlot = app.PlotsCheckBox.Value;
            % Contains position and velocity in format 
            % [x1 y1 vx1 vy1; x2 y2 vx2 vy2; ...]
            % First row is the leader
            agentData = app.AgentDataTable.Data;
            numAgents = app.NumAgentsEditField.Value;
            dt = app.dtEditField.Value;
            k = app.KEditField.Value;
            sigma = app.SigmaEditField.Value;
            beta = app.BetaEditField.Value;
            xPosString = app.XTrajectoryEditField.Value;
            yPosString = app.YTrajectoryEditField.Value;
            % Parametric position function
            % Ensure function is defined for x and y
            if any(size(xPosString)) && any(size(yPosString))
                positionFunction = [str2sym(xPosString) str2sym(yPosString)];
            else
                error('Bad Trajectory Function(s): Cannot be empty')
            end
            % Ensure positionFunction is symbolic in terms of only t
            syms t;
            if any(~ismember(symvar(positionFunction), t))
                error('Bad Trajectory Function(s): Must be a function of t')
            else
                velocityFunction = diff(positionFunction);
            end
            %% END User Variable Initialization %%
            
            %% Setup Plots %%
            % Setup current time field display format if dt not an integer
            if floor(dt)~=dt
                dtStr = split(num2str(dt),'.');
                spec = ['%0.' num2str(numel(dtStr{2})) 'f'];
                app.CurrentTimeEditField.ValueDisplayFormat = spec;
            end
            %% END Setup Plots %%
            
            %% Stats Initialization %%
            iteration = 1;
            numIterations = duration/dt;
            % Variables to store the position and velocity data for each agent throughout the simulation
            % The duration+1 account for the 0th iteration
            agentPositionHistory = zeros(numIterations+1, 2*numAgents);
            agentPositionHistory(1,:) = reshape(agentData(:,1:2)',1,[]);
            agentVelocityHistory = zeros(numIterations+1, 2*numAgents);
            agentVelocityHistory(1,:) = reshape(agentData(:,3:4)',1,[]);
            % Calculate leader's velocity over time
            agentVelocityHistory(:,1:2) = calcLeaderVelocity(velocityFunction,duration,dt);
            % Overwrite leader's initial velocity with parametric equation
            % at t=0, and update the table
            agentData(1,3:4) = agentVelocityHistory(1,1:2);
            app.AgentDataTable.Data = agentData;
            %% END Stats Initialization
            
            %% Simulation Loop %%
            while iteration < numIterations && ~app.isStopPushed
                app.CurrentTimeEditField.Value = time;
                
                % Calculate adjacency matrix
                A = calcA(k, sigma, beta, agentData(:,1:2));
                % Calculate Laplacian matrix
                L = calcL(A);
                
                % Updates the agents position
                agentData(:,1:2) = agentData(:,1:2) + dt*agentData(:,3:4);
                
                % Update agent velocity based on trigger
                if trigger(time)
                   % Update the agent velocities
                   agentData(:,3:4) = updateVelocity(L, dt, agentData(:,3:4));
                end
                % Overwrite leader velocity from values calculated previously
                agentData(1,3:4) = agentVelocityHistory(iteration+1, 1:2);
                
                time = time + dt;
                iteration = iteration + 1;
                
                %% Stats %%
                agentPositionHistory(iteration,:) = reshape(agentData(:,1:2)',1,[]);
                agentVelocityHistory(iteration,:) = reshape(agentData(:,3:4)',1,[]);
                %% END Stats %%
                
                %% Plotting %%
                if shouldPlot
                    app.AgentDataTable.Data = agentData;
                    plotArena(app, agentPositionHistory, iteration);
                end
                drawnow;
                %% END Plotting
            end
            %% END Simulation Loop %%
            % Update Current Iteration for the last iteration
            app.CurrentTimeEditField.Value = time;
            % Save data
            saveSimulationData(app, agentPositionHistory, agentVelocityHistory, iteration, dt);
        end

        % Button pushed function: StopButton
        function stopSimulation(app, event)
            app.isStopPushed = true;
        end

        % Button pushed function: LoadButton
        function loadAgentInfo(app, event)
            filename = app.FilenameEditField.Value;
            % Check if there is an existing .mat file
            if isfile(filename)
                file = load(filename);
                try
                    % Load matrix entries
                    app.AgentDataTable.Data = file.matrix;
                    % Load extra info
                    app.XTrajectoryEditField.Value = file.xFunction;
                    app.YTrajectoryEditField.Value = file.yFunction;
                    app.KEditField.Value = file.k;
                    app.SigmaEditField.Value = file.sigma;
                    app.BetaEditField.Value = file.beta;
                    app.NumAgentsEditField.Value = size(file.matrix,1);
                catch ME
                    disp(['Error: ' filename ' is corrupt. Please delete/fix and try again'])
                    disp(ME)
                    % Could use new blank data here, but that could
                    % overwrite any potentially recoverable data in the
                    % corrupt matrix
                    delete(app.Window);
                end
            else
                disp(['Error: No file with name: ' filename])
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Window = uifigure('Visible', 'off');
            app.Window.Name = 'Flocking Algorithm';
            app.ArenaPlot = uiaxes(app.Window);
            title(app.ArenaPlot, 'Arena')
            xlabel(app.ArenaPlot, 'X')
            ylabel(app.ArenaPlot, 'Y')
            app.KEditFieldLabel = uilabel(app.Window);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.Window, 'numeric');
            app.KEditField.Value = 1;
            app.BetaEditFieldLabel = uilabel(app.Window);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.Window, 'numeric');
            app.BetaEditField.Value = 0.25;
            app.SigmaLabel = uilabel(app.Window);
            app.SigmaLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.Window, 'numeric');
            app.SigmaEditField.Value = 1;
            app.LeaderTrajectoryLabel = uilabel(app.Window);
            app.LeaderTrajectoryLabel.Text = 'Leader Trajectory';
            app.xtLabel = uilabel(app.Window);
            app.xtLabel.Text = 'x(t)';
            app.XTrajectoryEditField = uieditfield(app.Window, 'text');
            app.ytLabel = uilabel(app.Window);
            app.ytLabel.Text = 'y(t)';
            app.YTrajectoryEditField = uieditfield(app.Window, 'text');
            app.NumberofAgentsLabel = uilabel(app.Window);
            app.NumberofAgentsLabel.Text = 'Number of Agents';
            app.NumAgentsEditField = uieditfield(app.Window, 'numeric');
            app.NumAgentsEditField.ValueChangedFcn = createCallbackFcn(app, @numAgentsChanged, true);
            app.NumAgentsEditField.Value = 5;
            app.DurationLabel = uilabel(app.Window);
            app.DurationLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.Window, 'numeric');
            app.DurationEditField.Value = 50;
            app.AgentDataTable = uitable(app.Window);
            app.AgentDataTable.ColumnName = {'X Position'; 'Y Position'; 'X Velocity'; 'Y Velocity'};
            app.AgentDataTable.ColumnEditable = [true true true true];
            app.StartButton = uibutton(app.Window, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @startSimulation, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.Window, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @stopSimulation, true);
            app.StopButton.Text = 'Stop';
            app.CurrentTimeEditFieldLabel = uilabel(app.Window);
            app.CurrentTimeEditFieldLabel.Text = 'Current Time';
            app.CurrentTimeEditField = uieditfield(app.Window, 'numeric');
            app.CurrentTimeEditField.Limits = [0 Inf];
            app.CurrentTimeEditField.Editable = 'off';
            app.dtEditFieldLabel = uilabel(app.Window);
            app.dtEditFieldLabel.Text = 'dt';
            app.dtEditField = uieditfield(app.Window, 'numeric');
            app.dtEditField.Value = 0.2;
            app.SimulationControlsLabel = uilabel(app.Window);
            app.SimulationControlsLabel.Text = 'Simulation Controls';
            app.AgentConfigurationLabel = uilabel(app.Window);
            app.AgentConfigurationLabel.Text = 'Agent Configuration';
            app.FilenameEditFieldLabel = uilabel(app.Window);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.Window, 'text');
            app.FilenameEditField.Value = 'agentInfo.mat';
            app.LoadButton = uibutton(app.Window, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadAgentInfo, true);
            app.LoadButton.Text = 'Load';
            app.AgentInformationLabel = uilabel(app.Window);
            app.AgentInformationLabel.Text = 'Agent Information';
            app.PlotsCheckBox = uicheckbox(app.Window);
            app.PlotsCheckBox.Text = 'Plots';
            app.PlotsCheckBox.Value = true;
            % Show the figure after all components are created
            app.Window.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FlockingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Window)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Window)
        end
    end
end