classdef MatrixEditorFlocking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Window               matlab.ui.Figure
        MatrixTable          matlab.ui.control.Table
        RowsEditField        matlab.ui.control.NumericEditField
        DimensionsLabel      matlab.ui.control.Label
        ColumnsEditField     matlab.ui.control.NumericEditField
        FileLabel            matlab.ui.control.Label
        NameEditFieldLabel   matlab.ui.control.Label
        NameEditField        matlab.ui.control.EditField
        SaveButton           matlab.ui.control.Button
        LoadButton           matlab.ui.control.Button
        ExtraInfoLabel       matlab.ui.control.Label
        NewButton            matlab.ui.control.Button
        RowsLabel            matlab.ui.control.Label
        ColumnsLabel         matlab.ui.control.Label
        xtEditFieldLabel     matlab.ui.control.Label
        xtEditField          matlab.ui.control.EditField
        ytEditFieldLabel     matlab.ui.control.Label
        ytEditField          matlab.ui.control.EditField
        KEditFieldLabel      matlab.ui.control.Label
        KEditField           matlab.ui.control.NumericEditField
        SigmaEditFieldLabel  matlab.ui.control.Label
        SigmaEditField       matlab.ui.control.NumericEditField
        BetaEditFieldLabel   matlab.ui.control.Label
        BetaEditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        data % data struct for matrix and/or func
        originalData % data struct for matrix and/or func upon last save
    end
    
    methods (Access = private)
        
        % Creates the default matrix, stores it in app.data, and displays it
        function defaultData(app)
            app.data.matrix = zeros(5,4);
            app.data.xFunction = '';
            app.data.yFunction = '';
            app.data.k = 1;
            app.data.sigma = 1;
            app.data.beta = 0.25;
            app.RowsEditField.Value = 5;
            app.ColumnsEditField.Value = 4;
            updateUI(app);
            app.originalData = app.data;
        end
        
        function saveData(app, filename)
            k = app.data.k;
            sigma = app.data.sigma;
            beta = app.data.beta;
            matrix = app.data.matrix;
            xFunction = app.data.xFunction;
            yFunction = app.data.yFunction;
            app.originalData = app.data;
            save(filename, 'matrix','xFunction','yFunction','k','sigma','beta');
        end
        
        % Take data from data struct and display it in the appropriate UI
        % elements
        function updateUI(app)
            app.xtEditField.Value = app.data.xFunction;
            app.ytEditField.Value = app.data.yFunction;
            app.KEditField.Value = app.data.k;
            app.SigmaEditField.Value = app.data.sigma;
            app.BetaEditField.Value = app.data.beta;
            app.MatrixTable.Data = app.data.matrix(:,:);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Config
            set(app.MatrixTable,'ColumnEditable',true,'ColumnWidth','auto');
            
            filename = app.NameEditField.Value;
            % Check if there is an existing .mat file
            if isfile(filename)
                file = load(filename);
                try
                    % Load matrix entries
                    app.data.matrix = file.matrix;
                    % Load extra info
                    app.data.xFunction = char(file.xFunction);
                    app.data.yFunction = char(file.yFunction);
                    app.data.k = file.k;
                    app.data.sigma = file.sigma;
                    app.data.beta = file.beta;
                    app.originalData = app.data;
                    dimensions = size(app.data.matrix);
                    % Repopulate dimension fields
                    app.RowsEditField.Value = dimensions(1);
                    app.ColumnsEditField.Value = dimensions(2);
                    %% Display loaded data on UI
                    updateUI(app);
                catch ME
                    disp(['Error: ' filename ' is corrupt. Please delete/fix and try again'])
                    disp(ME)
                    % Could use new blank data here, but that could
                    % overwrite any potentially recoverable data in the
                    % corrupt matrix
                    delete(app.Window);
                end
            else
                % Give user default data
                defaultData(app);
            end
        end

        % Button pushed function: LoadButton
        function loadFcn(app, event)
            filename = app.NameEditField.Value;
            % Check if there is an existing .mat file
            if isfile(filename)
                file = load(filename);
                try
                    % Load matrix entries
                    app.data.matrix = file.matrix;
                    % Load extra info
                    app.data.xFunction = file.xFunction;
                    app.data.yFunction = file.yFunction;
                    app.data.k = file.k;
                    app.data.sigma = file.sigma;
                    app.data.beta = file.beta;
                    app.originalData = app.data;
                    dimensions = size(app.data.matrix);
                    % Repopulate dimension fields
                    app.RowsEditField.Value = dimensions(1);
                    app.ColumnsEditField.Value = dimensions(2);
                    %% Display loaded data on UI
                    updateUI(app);
                catch ME
                    disp(['Error: ' filename ' is corrupt. Please delete/fix and try again'])
                    disp(ME)
                    % Could use new blank data here, but that could
                    % overwrite any potentially recoverable data in the
                    % corrupt matrix
                    delete(app.Window);
                end
            else
                disp(['Error: No file with name: ' filename])
            end
        end

        % Button pushed function: SaveButton
        function saveButtonFcn(app, event)
            filename = app.NameEditField.Value;
            if isfile(filename)
                selection = uiconfirm(app.Window,'File exists. Overwrite?', 'Confirm Save',...
                    'Options',{'Overwrite','Save as Copy', 'Cancel'},...
                    'DefaultOption',1,'CancelOption',3);
                switch (selection)
                    case 'Overwrite'
                        saveData(app, filename);
                    case 'Save as Copy'
                        fileParts = split(filename, '.');
                        filename = char(join([fileParts(1) '_copy.mat'],""));
                        saveData(app, filename);
                        % Also need to change the filename field
                        app.NameEditField.Value = filename;
                    case 'Cancel'
                        % Do nothing
                end
            else
                saveData(app, filename);
            end
        end

        % Value changed function: RowsEditField
        function rowsChanged(app, event)
            newNumRows = app.RowsEditField.Value;
            oldNumRows = size(app.data.matrix,1);
            deltaRows = newNumRows - oldNumRows;
            columns = size(app.data.matrix,2);
            % If we are deleting rows
            if deltaRows < 0
                app.data.matrix = app.data.matrix(1:newNumRows,:,:);
            % If we are adding rows
            elseif deltaRows > 0
                newRows = zeros(deltaRows, columns);
                app.data.matrix = cat(1, app.data.matrix, newRows);
            end
            app.MatrixTable.Data = app.data.matrix(:,:);
        end

        % Cell edit callback: MatrixTable
        function valueChanged(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.data.matrix(indices(1),indices(2)) = newData;
            app.MatrixTable.Data = app.data.matrix(:,:);
        end

        % Close request function: Window
        function closeFcn(app, event)
            % Prompt user to save if there are unsaved changes
            if ~isequal(app.data,app.originalData)
                selection = uiconfirm(app.Window,'Save changes before closing?', 'Confirm Save',...
                    'Options',{'Save & Exit','Exit', 'Cancel'},...
                    'DefaultOption',1,'CancelOption',3);
                switch (selection)
                    case 'Save & Exit'
                        filename = app.NameEditField.Value;
                        saveData(app, filename);
                        delete(app.Window);
                    case 'Exit'
                        delete(app.Window);
                    case 'Cancel'
                        % Do nothing
                end
            else
                delete(app.Window);
            end
        end

        % Button pushed function: NewButton
        function newFcn(app, event)
            % Prompt user to save any unsaved data
            if ~isequal(app.data,app.originalData)
                selection = uiconfirm(app.Window,'Save changes before opening new?', 'Confirm Save',...
                    'Options',{'Save','Don''t Save', 'Cancel'},...
                    'DefaultOption',1,'CancelOption',3);
                switch (selection)
                    case 'Save'
                        % Save stuff under specified filename
                        filename = app.NameEditField.Value;
                        saveData(app, filename);
                        % Reset to defaults
                        defaultData(app);
                    case 'Don''t Save'
                        % Reset to defaults
                        defaultData(app);
                    case 'Cancel'
                        % Do nothing
                end
            else
                % Reset to defaults
                defaultData(app);
            end
        end

        % Value changed function: xtEditField
        function xFunctionChanged(app, event)
            xFunction = app.xtEditField.Value;
            syms t;
            % If the function is not only in terms of t, clear it
            if any(~ismember(symvar(str2sym(xFunction)), t))
                app.xtEditField.Value = '';
                warning('Invalid Function: Ensure x(t) is an expression in terms of t');
            % Otherwise, accept it
            else
                app.data.xFunction = xFunction;
            end
        end

        % Value changed function: ytEditField
        function yFunctionChanged(app, event)
            yFunction = app.ytEditField.Value;
            syms t;
            % If the function is not only in terms of t, clear it
            if any(~ismember(symvar(str2sym(yFunction)), t))
                app.ytEditField.Value = '';
                warning('Invalid Function: Ensure y(t) is an expression in terms of t');
            % Otherwise, accept it
            else
                app.data.yFunction = yFunction;
            end
        end

        % Value changed function: KEditField
        function kChanged(app, event)
            app.data.k = app.KEditField.Value;
        end

        % Value changed function: SigmaEditField
        function sigmaChanged(app, event)
            app.data.sigma = app.SigmaEditField.Value;
        end

        % Value changed function: BetaEditField
        function betaChanged(app, event)
            app.data.beta = app.BetaEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Window = uifigure('Visible', 'off');
            app.Window.Name = 'Matrix Editor';
            app.Window.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.MatrixTable = uitable(app.Window);
            app.MatrixTable.ColumnName = {'X Position'; 'Y Position'; 'X Velocity'; 'Y Velocity'};
            app.MatrixTable.CellEditCallback = createCallbackFcn(app, @valueChanged, true);
            app.RowsEditField = uieditfield(app.Window, 'numeric');
            app.RowsEditField.Limits = [1 Inf];
            app.RowsEditField.ValueChangedFcn = createCallbackFcn(app, @rowsChanged, true);
            app.RowsEditField.Value = 5;
            app.DimensionsLabel = uilabel(app.Window);
            app.DimensionsLabel.Text = 'Dimensions';
            app.ColumnsEditField = uieditfield(app.Window, 'numeric');
            app.ColumnsEditField.Limits = [1 Inf];
            app.ColumnsEditField.Editable = 'off';
            app.ColumnsEditField.Value = 4;
            app.FileLabel = uilabel(app.Window);
            app.FileLabel.Text = 'File';
            app.NameEditFieldLabel = uilabel(app.Window);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Window, 'text');
            app.NameEditField.Value = 'agentInfo.mat';
            app.SaveButton = uibutton(app.Window, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonFcn, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.Window, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadFcn, true);
            app.LoadButton.Text = 'Load';
            app.ExtraInfoLabel = uilabel(app.Window);
            app.ExtraInfoLabel.Text = 'Extra Info';
            app.NewButton = uibutton(app.Window, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @newFcn, true);
            app.NewButton.Text = 'New';
            app.RowsLabel = uilabel(app.Window);
            app.RowsLabel.Text = 'Rows';
            app.ColumnsLabel = uilabel(app.Window);
            app.ColumnsLabel.Text = 'Columns';
            app.xtEditFieldLabel = uilabel(app.Window);
            app.xtEditFieldLabel.Text = 'x(t)';
            app.xtEditField = uieditfield(app.Window, 'text');
            app.xtEditField.ValueChangedFcn = createCallbackFcn(app, @xFunctionChanged, true);
            app.ytEditFieldLabel = uilabel(app.Window);
            app.ytEditFieldLabel.Text = 'y(t)';
            app.ytEditField = uieditfield(app.Window, 'text');
            app.ytEditField.ValueChangedFcn = createCallbackFcn(app, @yFunctionChanged, true);
            app.KEditFieldLabel = uilabel(app.Window);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.Window, 'numeric');
            app.KEditField.Limits = [1e-05 Inf];
            app.KEditField.ValueChangedFcn = createCallbackFcn(app, @kChanged, true);
            app.KEditField.Value = 1;
            app.SigmaEditFieldLabel = uilabel(app.Window);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.Window, 'numeric');
            app.SigmaEditField.Limits = [1e-05 Inf];
            app.SigmaEditField.ValueChangedFcn = createCallbackFcn(app, @sigmaChanged, true);
            app.SigmaEditField.Value = 1;
            app.BetaEditFieldLabel = uilabel(app.Window);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.Window, 'numeric');
            app.BetaEditField.Limits = [1e-05 Inf];
            app.BetaEditField.ValueChangedFcn = createCallbackFcn(app, @betaChanged, true);
            app.BetaEditField.Value = 0.25;
            % Show the figure after all components are created
            app.Window.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatrixEditorFlocking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Window)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Window)
        end
    end
end