classdef FormationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Window                        matlab.ui.Figure
        ArenaPlot                     matlab.ui.control.UIAxes
        DistancePlot                  matlab.ui.control.UIAxes
        AgentInformationLabel         matlab.ui.control.Label
        FileNameEditField_2Label      matlab.ui.control.Label
        AgentNameEditField            matlab.ui.control.EditField
        LoadAgentButton               matlab.ui.control.Button
        AgentTable                    matlab.ui.control.Table
        StartButton                   matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        DurationLabel                 matlab.ui.control.Label
        DurationEditField             matlab.ui.control.NumericEditField
        CurrentTimeEditFieldLabel     matlab.ui.control.Label
        CurrentTimeEditField          matlab.ui.control.NumericEditField
        dtEditFieldLabel              matlab.ui.control.Label
        dtEditField                   matlab.ui.control.NumericEditField
        NumberofAgentsEditFieldLabel  matlab.ui.control.Label
        NumberofAgentsEditField       matlab.ui.control.NumericEditField
        SimulationControlsLabel       matlab.ui.control.Label
        ParametersLabel               matlab.ui.control.Label
        PlotsCheckBox                 matlab.ui.control.CheckBox
    end

    properties (Access = private)
        isStopPushed;
    end
    
    methods (Access = private)
        
        function plotArena(app, agentPositions, A)
            % Setting up the plot
            delta = 1/20;
            numAgents = size(agentPositions(:,1:2),1);
            app.ArenaPlot.ColorOrderIndex = 1;
            cla(app.ArenaPlot)
            hold(app.ArenaPlot,'on');
            
            % Agent dots
            plot(app.ArenaPlot,agentPositions(:,1),agentPositions(:,2),'Marker','o');
            % Agent Labeling
            text(app.ArenaPlot, agentPositions(:,1)+delta, agentPositions(:,2)+delta,...
                cellfun(@num2str,num2cell((1:numAgents)'),'UniformOutput',false));
            
            % Adjusting Label Axes to fit all agents
            app.ArenaPlot.XLim = ([min(agentPositions(:,1)) - 1, max(agentPositions(:,1)) + 1]);
            app.ArenaPlot.YLim = ([min(agentPositions(:,2)) - 1, max(agentPositions(:,2)) + 1]);
            
            % Adding communication lines between agents
            % Get plot values (can't do gplot directly to specified axis in 2019a)
            [X,Y] = gplot(A, agentPositions);
            % Plot values
            plot(app.ArenaPlot, X, Y,'-or','LineWidth',1,'MarkerFaceColor','auto');
             
            hold(app.ArenaPlot,'off');
        end
        
        function plotDistance(app, distanceToAverage, iteration)
            % Setting up the plot
            app.DistancePlot.ColorOrderIndex = 1;
            cla(app.DistancePlot)
            hold(app.DistancePlot,'on');
            
            % Plotting the distance from the average for each agent
            plot(app.DistancePlot, distanceToAverage(1:iteration,:));
            
            hold(app.DistancePlot,'off');
        end
        
        function saveSimulationData(~,agentPositionHistory,agentEnergyHistory,...
                distanceToAverage, f, dt)
            ssName = 'agentData.xlsx';
            matName = 'agentData.mat';
            if isfile(ssName)
                delete(ssName)
            end
            if isfile(matName)
                delete(matName);
            end
            % Trim history to final iteration
            agentPositionHistory = agentPositionHistory(1:f,:);
            agentEnergyHistory = agentEnergyHistory(1:f,:);
            distanceToAverage = distanceToAverage(1:f,:);
            save(matName,'agentPositionHistory','agentEnergyHistory','distanceToAverage')
            
            % Generate column headings for table
            N = (1:size(agentEnergyHistory,2))';
            posHeadings = reshape(cat(2,cellstr(num2str(N,'x%d')),cellstr(num2str(N,'y%d')))',1,[]);
            agentHeadings = cellstr(num2str(N,'Agent%d'))';
            
            % Timestamp for rows
            timeStamp = dt*(0:f-1)';
            posHeadings = cat(2,cellstr('t'),posHeadings);
            agentHeadings = cat(2,cellstr('t'),agentHeadings);
            % Add timestamps to data
            agentPositionHistory = cat(2,timeStamp,agentPositionHistory);
            agentEnergyHistory = cat(2,timeStamp,agentEnergyHistory);
            distanceToAverage = cat(2,timeStamp,distanceToAverage);
            
            % Generate tables
            agentPositionHistory = array2table(agentPositionHistory, 'VariableNames',posHeadings);
            agentEnergyHistory = array2table(agentEnergyHistory,'VariableNames',agentHeadings);
            distanceToAverage = array2table(distanceToAverage,'VariableNames',agentHeadings);
            
            writetable(agentPositionHistory,ssName,'FileType','spreadsheet','Sheet','Agent Position History');
            writetable(agentEnergyHistory,ssName,'FileType','spreadsheet','Sheet','Agent Energy History');
            writetable(distanceToAverage,ssName,'FileType','spreadsheet','Sheet','Distance To Average');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AgentTable.Data = zeros(5,6);
            app.AgentTable.ColumnWidth = 'auto';
            % Suppress warnings for spreadsheet writing
            warning('off','MATLAB:xlswrite:AddSheet');
        end

        % Button pushed function: LoadAgentButton
        function loadAgentInfo(app, event)
            % Load data from external .mat to populate AgentTable
            filename = app.AgentNameEditField.Value;
            if isfile(filename)
                file = load(filename);
                try
                    % Put all data into the table
                    app.AgentTable.Data = file.matrix;
                    app.NumberofAgentsEditField.Value = size(app.AgentTable.Data,1);
                catch
                    warning('Bad Agent Information file: File must contain info in variable ''matrix''')
                end
            else
                warning(['Bad Agent Information file: No such file ''' filename ''''])
            end
        end

        % Button pushed function: StartButton
        function startSimulation(app, event)
% Main function to run simulation
app.isStopPushed = false;

%% Initialize Values %%
agentData = app.AgentTable.Data;
numAgents = size(agentData,1);
dt = app.dtEditField.Value;
time = 0;
duration = app.DurationEditField.Value;
shouldPlot = app.PlotsCheckBox.Value;

% numIterations is duration in discrete time steps
numIterations = duration/dt;
% iteration is used to keep track of discrete time steps
iteration = 1;
%% END Initialize Values %%

%% Initialize Plots %%
cla(app.DistancePlot);
cla(app.ArenaPlot);
app.DistancePlot.XLim = [0 numIterations];
% Setup current time field display format if dt not an integer
if floor(dt)~=dt
    dtStr = split(num2str(dt),'.');
    spec = ['%0.' num2str(numel(dtStr{2})) 'f'];
    app.CurrentTimeEditField.ValueDisplayFormat = spec;
end
%% END Initialize Plots %%

%% Stats setup %%
% Each row in agentPositionHistory is position at a given iteration. Stored
% in [x1 y1 x2 y2 ...] format
agentPositionHistory = zeros(numIterations, 2*numAgents);
agentPositionHistory(1,:) = reshape(agentData(:,1:2)',1,[]);

agentEnergyHistory = zeros(numIterations, numAgents);
agentEnergyHistory(1,:) = reshape(agentData(:,6),1,[]);

% calculate initial averagePosition
averagePosition = mean(agentData(:,1:2));
% Each row in distanceToAverage has numAgents columns, representing the
% Euclidean distance between that agent and average position for that
% iteration
distanceToAverage = zeros(numIterations,numAgents);
distanceToAverage(1,:) = pdist2(agentData(:,1:2),averagePosition)';
%% END Stats setup %%

% RUNNING THE SIMULATION
while iteration < numIterations && ~app.isStopPushed
    % Update UI for current iteration
    app.CurrentTimeEditField.Value = time;
    
    %% Matrix Functions %%
    % calcA should return an adjacency matrix using the agent state (positions)
    A = calcA(agentData(:,1:2));
    % Calculate Laplacian matrix from A (calculates D internally)
    L = calcL(A);
    %% END Matrix Functions %%
    
    %% Move Agents %%
    % Call student function
    agentData = moveAgents(agentData, L, time, dt);
    % Update UI
    if shouldPlot
        app.AgentTable.Data = agentData;
    end
    % Update agent history
    agentPositionHistory(iteration+1,:) = reshape(agentData(:,1:2)',1,[]);
    agentEnergyHistory(iteration+1,:) = reshape(agentData(:,6),1,[]);
    % update averagePosition
    averagePosition = mean(agentData(:,1:2));
    % Calculates the each agent's distance to the average and stores it in the distance variable
    distanceToAverage(iteration+1,:) = pdist2(agentData(:,1:2),averagePosition)';
    %% END Move Agents %%
    
    time = time + dt;
    iteration = iteration + 1;
    
    %% Plots %%
    if shouldPlot
        plotArena(app, agentData(:,1:2), A);
        plotDistance(app, distanceToAverage, iteration);
    end
    %% END Plots %%
    
    drawnow;
end

% Writes Position History to an Excel Spreadsheet
saveSimulationData(app,agentPositionHistory,agentEnergyHistory,...
    distanceToAverage,iteration,dt);
        end

        % Button pushed function: StopButton
        function stopSimulation(app, event)
            app.isStopPushed = true;
        end

        % Value changed function: NumberofAgentsEditField
        function numAgentsChanged(app, event)
            numAgents = app.NumberofAgentsEditField.Value;
            if size(app.AgentTable.Data,1) < numAgents
                app.AgentTable.Data = vertcat(app.AgentTable.Data,zeros(numAgents...
                    - size(app.AgentTable.Data,1),6));
            elseif size(app.AgentTable.Data,1) > numAgents
                app.AgentTable.Data = app.AgentTable.Data(1:numAgents,:);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Window = uifigure('Visible', 'off');
            app.Window.Name = 'Formation Algorithm';
            app.ArenaPlot = uiaxes(app.Window);
            title(app.ArenaPlot, 'Arena')
            xlabel(app.ArenaPlot, 'X')
            ylabel(app.ArenaPlot, 'Y')
            app.DistancePlot = uiaxes(app.Window);
            title(app.DistancePlot, 'Distance From Average')
            xlabel(app.DistancePlot, 'Iteration')
            ylabel(app.DistancePlot, 'Distance')
            app.AgentInformationLabel = uilabel(app.Window);
            app.AgentInformationLabel.Text = 'Agent Information';
            app.FileNameEditField_2Label = uilabel(app.Window);
            app.FileNameEditField_2Label.Text = 'File Name';
            app.AgentNameEditField = uieditfield(app.Window, 'text');
            app.AgentNameEditField.Value = 'agentInfo.mat';
            app.LoadAgentButton = uibutton(app.Window, 'push');
            app.LoadAgentButton.ButtonPushedFcn = createCallbackFcn(app, @loadAgentInfo, true);
            app.LoadAgentButton.Text = 'Load';
            app.AgentTable = uitable(app.Window);
            app.AgentTable.ColumnName = {'X'; 'Y'; 'Delay'; 'X Offset'; 'Y Offset'; 'Energy'};
            app.AgentTable.ColumnEditable = [true true true true true true];
            app.StartButton = uibutton(app.Window, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @startSimulation, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.Window, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @stopSimulation, true);
            app.StopButton.Text = 'Stop';
            app.DurationLabel = uilabel(app.Window);
            app.DurationLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.Window, 'numeric');
            app.DurationEditField.Limits = [1 Inf];
            app.DurationEditField.Value = 20;
            app.CurrentTimeEditFieldLabel = uilabel(app.Window);
            app.CurrentTimeEditFieldLabel.Text = 'Current Time';
            app.CurrentTimeEditField = uieditfield(app.Window, 'numeric');
            app.CurrentTimeEditField.Limits = [0 Inf];
            app.CurrentTimeEditField.Editable = 'off';
            app.dtEditFieldLabel = uilabel(app.Window);
            app.dtEditFieldLabel.Text = 'dt';
            app.dtEditField = uieditfield(app.Window, 'numeric');
            app.dtEditField.Limits = [1e-10 1e+100];
            app.dtEditField.Value = 0.2;
            app.NumberofAgentsEditFieldLabel = uilabel(app.Window);
            app.NumberofAgentsEditFieldLabel.Text = 'Number of Agents';
            app.NumberofAgentsEditField = uieditfield(app.Window, 'numeric');
            app.NumberofAgentsEditField.Limits = [1 Inf];
            app.NumberofAgentsEditField.ValueChangedFcn = createCallbackFcn(app, @numAgentsChanged, true);
            app.NumberofAgentsEditField.Value = 4;
            app.SimulationControlsLabel = uilabel(app.Window);
            app.SimulationControlsLabel.Text = 'Simulation Controls';
            app.ParametersLabel = uilabel(app.Window);
            app.ParametersLabel.Text = 'Parameters';
            app.PlotsCheckBox = uicheckbox(app.Window);
            app.PlotsCheckBox.Text = 'Plots';
            app.PlotsCheckBox.Value = true;
            % Show the figure after all components are created
            app.Window.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FormationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Window)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Window)
        end
    end
end