classdef MatrixEditorFormation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Window                        matlab.ui.Figure
        MatrixTable                   matlab.ui.control.Table
        FileLabel                     matlab.ui.control.Label
        NameEditFieldLabel            matlab.ui.control.Label
        NameEditField                 matlab.ui.control.EditField
        SaveButton                    matlab.ui.control.Button
        LoadButton                    matlab.ui.control.Button
        NewButton                     matlab.ui.control.Button
        NumberofAgentsEditFieldLabel  matlab.ui.control.Label
        NumberofAgentsEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        data % data struct for matrix and/or func
        originalData % data struct for matrix and/or func upon last save
    end
    
    methods (Access = private)
        
        % Creates the default matrix, stores it in app.data, and displays it
        function defaultData(app)
            app.data.matrix = zeros(5,6);
            app.NumberofAgentsEditField.Value = 5;
            app.MatrixTable.Data = app.data.matrix;
        end
        
        function saveData(app, filename)
            matrix = app.data.matrix;
            app.originalData = app.data;
            save(filename, 'matrix');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            filename = app.NameEditField.Value;
            % Check if there is an existing .mat file
            if isfile(filename)
                file = load(filename);
                try
                    app.data.matrix = file.matrix;
                    app.originalData = app.data;
                    numAgents = size(app.data.matrix,1);
                    % Repopulate num agents field
                    app.NumberofAgentsEditField.Value = numAgents;
                    %% Display loaded data on UI
                    app.MatrixTable.Data = app.data.matrix;
                catch ME
                    disp(['Error: ' filename ' is corrupt. Please delete/fix and try again'])
                    disp(ME)
                    % Could use new blank data here, but that could
                    % overwrite any potentially recoverable data in the
                    % corrupt matrix
                    delete(app.Window);
                end
            else
                % Give user default data
                defaultData(app);
            end
        end

        % Button pushed function: LoadButton
        function loadFcn(app, event)
            filename = app.NameEditField.Value;
            % Check if there is an existing .mat file
            if isfile(filename)
                file = load(filename);
                try
                    app.data.matrix = file.matrix;
                    app.originalData = app.data;
                    numAgents = size(app.data.matrix,1);
                    % Repopulate num agents field
                    app.NumberofAgentsEditField.Value = numAgents;
                    %% Display loaded data on UI
                    app.MatrixTable.Data = app.data.matrix;
                catch ME
                    disp(['Error: ' filename ' is corrupt. Please delete/fix and try again'])
                    disp(ME)
                    % Could use new blank data here, but that could
                    % overwrite any potentially recoverable data in the
                    % corrupt matrix
                    delete(app.Window);
                end
            else
                disp(['Error: No file with name: ' filename])
            end
        end

        % Button pushed function: SaveButton
        function saveButtonFcn(app, event)
            filename = app.NameEditField.Value;
            if isfile(filename)
                selection = uiconfirm(app.Window,'File exists. Overwrite?', 'Confirm Save',...
                    'Options',{'Overwrite','Save as Copy', 'Cancel'},...
                    'DefaultOption',1,'CancelOption',3);
                switch (selection)
                    case 'Overwrite'
                        saveData(app, filename);
                    case 'Save as Copy'
                        fileParts = split(filename, '.');
                        filename = char(join([fileParts(1) '_copy.mat'],""));
                        saveData(app, filename);
                        % Also need to change the filename field
                        app.NameEditField.Value = filename;
                    case 'Cancel'
                        % Do nothing
                end
            else
                saveData(app, filename);
            end
        end

        % Cell edit callback: MatrixTable
        function valueChanged(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.data.matrix(indices(1),indices(2)) = newData;
            app.MatrixTable.Data = app.data.matrix;
        end

        % Close request function: Window
        function closeFcn(app, event)
            % Prompt user to save if there are unsaved changes
            if ~isequal(app.data,app.originalData)
                selection = uiconfirm(app.Window,'Save changes before closing?', 'Confirm Save',...
                    'Options',{'Save & Exit','Exit', 'Cancel'},...
                    'DefaultOption',1,'CancelOption',3);
                switch (selection)
                    case 'Save & Exit'
                        filename = app.NameEditField.Value;
                        saveData(app, filename);
                        delete(app.Window);
                    case 'Exit'
                        delete(app.Window);
                    case 'Cancel'
                        % Do nothing
                end
            else
                delete(app.Window);
            end
        end

        % Button pushed function: NewButton
        function newFcn(app, event)
            % Prompt user to save any unsaved data
            if ~isequal(app.data,app.originalData)
                selection = uiconfirm(app.Window,'Save changes before opening new?', 'Confirm Save',...
                    'Options',{'Save','Don''t Save', 'Cancel'},...
                    'DefaultOption',1,'CancelOption',3);
                switch (selection)
                    case 'Save'
                        % Save stuff under specified filename
                        filename = app.NameEditField.Value;
                        saveData(app, filename);
                        % Reset to defaults
                        defaultData(app);
                    case 'Don''t Save'
                        % Reset to defaults
                        defaultData(app);
                    case 'Cancel'
                        % Do nothing
                end
            else
                % Reset to defaults
                defaultData(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Window = uifigure('Visible', 'off');
            app.Window.Name = 'Matrix Editor';
            app.Window.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.MatrixTable = uitable(app.Window);
            app.MatrixTable.ColumnName = {'X'; 'Y'; 'Delay'; 'X Offset'; 'Y Offset'; 'Energy'};
            app.MatrixTable.ColumnEditable = true;
            app.MatrixTable.CellEditCallback = createCallbackFcn(app, @valueChanged, true);
            app.FileLabel = uilabel(app.Window);
            app.FileLabel.Text = 'File';
            app.NameEditFieldLabel = uilabel(app.Window);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Window, 'text');
            app.NameEditField.Value = 'agentInfo.mat';
            app.SaveButton = uibutton(app.Window, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonFcn, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.Window, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadFcn, true);
            app.LoadButton.Text = 'Load';
            app.NewButton = uibutton(app.Window, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @newFcn, true);
            app.NewButton.Text = 'New';
            app.NumberofAgentsEditFieldLabel = uilabel(app.Window);
            app.NumberofAgentsEditFieldLabel.Text = 'Number of Agents';
            app.NumberofAgentsEditField = uieditfield(app.Window, 'numeric');
            app.NumberofAgentsEditField.Limits = [1 Inf];
            app.NumberofAgentsEditField.RoundFractionalValues = 'on';
            app.NumberofAgentsEditField.Value = 5;
            % Show the figure after all components are created
            app.Window.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatrixEditorFormation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Window)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Window)
        end
    end
end