classdef MatrixEditorLloyd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Window                 matlab.ui.Figure
        MatrixTable            matlab.ui.control.Table
        RowsEditField          matlab.ui.control.NumericEditField
        DimensionsLabel        matlab.ui.control.Label
        ColumnsEditField       matlab.ui.control.NumericEditField
        DepthEditField         matlab.ui.control.NumericEditField
        ViewLabel              matlab.ui.control.Label
        CurrentDepthEditField  matlab.ui.control.NumericEditField
        FileLabel              matlab.ui.control.Label
        NameEditFieldLabel     matlab.ui.control.Label
        NameEditField          matlab.ui.control.EditField
        SaveButton             matlab.ui.control.Button
        LoadButton             matlab.ui.control.Button
        MatrixTypeLabel        matlab.ui.control.Label
        ContinuousCheckBox     matlab.ui.control.CheckBox
        FunctionEditField      matlab.ui.control.EditField
        DepthLabel             matlab.ui.control.Label
        CurrentDepthLabel      matlab.ui.control.Label
        FunctionLabel          matlab.ui.control.Label
        NewButton              matlab.ui.control.Button
        DensityCheckBox        matlab.ui.control.CheckBox
        RowsLabel              matlab.ui.control.Label
        ColumnsLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        data % data struct for matrix and/or func
        originalData % data struct for matrix and/or func upon last save
    end
    
    methods (Access = private)
        
        % Creates the default matrix, stores it in app.data, and displays it
        function defaultData(app)
            app.data.func = '';
            app.data.matrix = cell(5,5,1);
            app.RowsEditField.Value = 5;
            app.ColumnsEditField.Value = 5;
            app.DepthEditField.Value = 1;
            app.CurrentDepthEditField.Value = 1;
            app.MatrixTable.Data = app.data.matrix(:,:,1);
        end
        
        % Handles the enable/disable of UI elements for continuity. Called when changing
        % function types, and when loading from file
        function toggleContinuous(app, isContinuous)
            if isContinuous
                % Moving to continuous
                % Enable function field
                set(app.FunctionEditField,'Editable',true,'Enable',true);
                set(app.FunctionLabel,'Enable',true);
                % Disable table editing
                set(app.MatrixTable,'Enable','off');
                % Disable Row/Column editing
                set(app.ColumnsEditField,'Editable',false,'Enable',false);
                set(app.ColumnsLabel,'Enable',false);
                set(app.RowsEditField,'Editable',false,'Enable',false);
                set(app.RowsLabel,'Enable',false);
            else
                % Moving to discrete
                % Disable function field
                set(app.FunctionEditField,'Editable',false,'Enable',false);
                set(app.FunctionLabel,'Enable',false);
                % Enable table editing
                set(app.MatrixTable,'Enable','on');
                % Enable Row/Column editing
                set(app.ColumnsEditField,'Editable',true,'Enable',true);
                set(app.ColumnsLabel,'Enable',true);
                set(app.RowsEditField,'Editable',true,'Enable',true);
                set(app.RowsLabel,'Enable',true);
            end
            % Check continuous box
            set(app.ContinuousCheckBox,'Value',isContinuous);
        end
        
        % Handles the enable/disable of UI elements for density. Called when changing
        % function types, and when loading from file
        function toggleDensity(app, isDensity)
            if isDensity
                % Moving to density matrix
                % Disable depth
                set(app.DepthEditField,'Value',1,'Editable',false,'Enable',false);
                set(app.DepthLabel,'Enable',false);
                set(app.CurrentDepthEditField,'Value',1,'Editable',false,'Enable',false);
                set(app.CurrentDepthLabel,'Enable',false);
                % Enable continuous (don't alter value)
                set(app.ContinuousCheckBox,'Enable',true);
            else
                % Moving to non-density matrix
                % Enable depth
                set(app.DepthEditField,'Editable',true,'Enable',true);
                set(app.DepthLabel,'Enable',true);
                set(app.CurrentDepthEditField,'Editable',true,'Enable',true);
                set(app.CurrentDepthLabel,'Enable',true);
                % Disable continuous (don't alter value)
                set(app.ContinuousCheckBox,'Enable',false);
            end
            % Check density box
            set(app.DensityCheckBox,'Value',isDensity)
        end
        
        function saveData(app, filename)
            isDensity = app.DensityCheckBox.Value;
            isContinuous = app.ContinuousCheckBox.Value;
            % If continuous, save matrix as string values
            if isContinuous
                matrix = app.data.matrix;
            elseif isDensity
                matrix = str2sym(app.data.matrix);
            else
                matrix = app.data.matrix;
            end
            % Convert func from string to sym before save
            func = str2sym(app.data.func);
            app.originalData = app.data;
            save(filename, 'matrix','isContinuous','isDensity','func');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Config
            set(app.MatrixTable,'ColumnEditable',true,'ColumnWidth','auto');
            
            filename = app.NameEditField.Value;
            % Check if there is an existing .mat file
            if isfile(filename)
                file = load(filename);
                try
                    % Get info about matrix
                    isDensity = file.isDensity;
                    isContinuous = file.isContinuous;
                    % Load matrix entries
                    if isContinuous
                        app.data.matrix = file.matrix;
                    elseif isDensity
                        % If entries were stored as symbolic equations, they need
                        % to be converted to char to be displayed in table
                        app.data.matrix = arrayfun(@char, file.matrix, 'UniformOutput', false);
                    else
                        app.data.matrix = file.matrix;
                    end
                    % Load func
                    % Empty sym equation has size [0 0]
                    if isequal(size(file.func), [0 0])
                        app.data.func = '';
                    else
                        app.data.func = char(file.func);
                    end
                    app.originalData = app.data;
                    dimensions = size(app.data.matrix);
                    % Repopulate dimension fields
                    app.RowsEditField.Value = dimensions(1);
                    app.ColumnsEditField.Value = dimensions(2);
                    % If the matrix has depth > 1, ndims(app.data) == 3
                    if ndims(app.data.matrix) == 3
                        app.DepthEditField.Value = dimensions(3);
                    else
                        app.DepthEditField.Value = 1;
                    end
                    %% Display loaded data on UI
                    app.FunctionEditField.Value = app.data.func;
                    app.MatrixTable.Data = app.data.matrix(:,:,1);
                    
                    % UI toggles
                    toggleDensity(app, isDensity);
                    toggleContinuous(app, isContinuous);
                catch ME
                    disp(['Error: ' filename ' is corrupt. Please delete/fix and try again'])
                    disp(ME)
                    % Could use new blank data here, but that could
                    % overwrite any potentially recoverable data in the
                    % corrupt matrix
                    delete(app.Window);
                end
            else
                % Give user default data
                defaultData(app);
            end
        end

        % Button pushed function: LoadButton
        function loadFcn(app, event)
            filename = app.NameEditField.Value;
            % Check if there is an existing .mat file
            if isfile(filename)
                file = load(filename);
                try
                    % Get info about matrix
                    isDensity = file.isDensity;
                    isContinuous = file.isContinuous;
                    % Load matrix entries
                    if isContinuous
                        app.data.matrix = file.matrix;
                    elseif isDensity
                        % If entries were stored as symbolic equations, they need
                        % to be converted to char to be displayed in table
                        app.data.matrix = arrayfun(@char, file.matrix, 'UniformOutput', false);
                    else
                        app.data.matrix = file.matrix;
                    end
                    % Load func
                    % Empty sym equation has size [0 0]
                    if isequal(size(file.func), [0 0])
                        app.data.func = '';
                    else
                        app.data.func = char(file.func);
                    end
                    app.originalData = app.data;
                    dimensions = size(app.data.matrix);
                    % Repopulate dimension fields
                    app.RowsEditField.Value = dimensions(1);
                    app.ColumnsEditField.Value = dimensions(2);
                    % If the matrix has depth > 1, ndims(app.data) == 3
                    if ndims(app.data.matrix) == 3
                        app.DepthEditField.Value = dimensions(3);
                    else
                        app.DepthEditField.Value = 1;
                    end
                    %% Display loaded data on UI
                    app.FunctionEditField.Value = app.data.func;
                    app.MatrixTable.Data = app.data.matrix(:,:,1);
                    
                    % UI toggles
                    toggleDensity(app, isDensity);
                    toggleContinuous(app, isContinuous);
                catch ME
                    disp(['Error: ' filename ' is corrupt. Please delete/fix and try again'])
                    disp(ME)
                    % Could use new blank data here, but that could
                    % overwrite any potentially recoverable data in the
                    % corrupt matrix
                    delete(app.Window);
                end
            else
                disp(['Error: No file with name: ' filename])
            end
        end

        % Button pushed function: SaveButton
        function saveButtonFcn(app, event)
            filename = app.NameEditField.Value;
            if isfile(filename)
                selection = uiconfirm(app.Window,'File exists. Overwrite?', 'Confirm Save',...
                    'Options',{'Overwrite','Save as Copy', 'Cancel'},...
                    'DefaultOption',1,'CancelOption',3);
                switch (selection)
                    case 'Overwrite'
                        saveData(app, filename);
                    case 'Save as Copy'
                        fileParts = split(filename, '.');
                        filename = char(join([fileParts(1) '_copy.mat'],""));
                        saveData(app, filename);
                        % Also need to change the filename field
                        app.NameEditField.Value = filename;
                    case 'Cancel'
                        % Do nothing
                end
            else
                saveData(app, filename);
            end
        end

        % Value changed function: RowsEditField
        function rowsChanged(app, event)
            newNumRows = app.RowsEditField.Value;
            oldNumRows = size(app.data.matrix,1);
            deltaRows = newNumRows - oldNumRows;
            depth = size(app.data.matrix,3);
            columns = size(app.data.matrix,2);
            % If we are deleting rows
            if deltaRows < 0
                app.data.matrix = app.data.matrix(1:newNumRows,:,:);
            % If we are adding rows
            elseif deltaRows > 0
                newRows = cell(deltaRows, columns, depth);
                app.data.matrix = cat(1, app.data.matrix, newRows);
            end
            currDepth = app.CurrentDepthEditField.Value;
            app.MatrixTable.Data = app.data.matrix(:,:,currDepth);
        end

        % Value changed function: ColumnsEditField
        function columnsChanged(app, event)
            newNumCols = app.ColumnsEditField.Value;
            oldNumCols = size(app.data.matrix,2);
            deltaCols = newNumCols - oldNumCols;
            depth = size(app.data.matrix,3);
            rows = size(app.data.matrix,1);
            % If we are deleting columns
            if deltaCols < 0
                app.data.matrix = app.data.matrix(:,1:newNumCols,:);
            % If we are adding columns
            elseif deltaCols > 0
                newCols = cell(rows, deltaCols, depth);
                app.data.matrix = cat(2, app.data.matrix, newCols);
            end
            currDepth = app.CurrentDepthEditField.Value;
            app.MatrixTable.Data = app.data.matrix(:,:,currDepth);
        end

        % Value changed function: DepthEditField
        function depthChanged(app, event)
            newDepth = app.DepthEditField.Value;
            oldDepth = size(app.data.matrix,3);
            deltaDepth = newDepth - oldDepth;
            currDepth = app.CurrentDepthEditField.Value;
            rows = size(app.data.matrix,1);
            columns = size(app.data.matrix,2);
            % If we are deleting depth
            if deltaDepth < 0
                app.data.matrix = app.data.matrix(:,:,1:newDepth);
                if newDepth<currDepth
                    app.CurrentDepthEditField.Value = newDepth;
                    % Only need to change view if cutting off currently
                    % viewed matrix
                    app.MatrixTable.Data = app.data.matrix(:,:,newDepth);
                end
            % If we are adding columns
            elseif deltaDepth > 0
                newDepthMats = cell(rows, columns, deltaDepth);
                app.data.matrix = cat(3, app.data.matrix, newDepthMats);
            end
        end

        % Value changed function: CurrentDepthEditField
        function viewChanged(app, event)
            value = app.CurrentDepthEditField.Value;
            if value>size(app.data.matrix,3)
                app.MatrixTable.Data = app.data.matrix(:,:,size(app.data.matrix,3));
                app.CurrentDepthEditField.Value = size(app.data.matrix,3);
            else
                app.MatrixTable.Data = app.data.matrix(:,:,value);
            end
        end

        % Cell edit callback: MatrixTable
        function valueChanged(app, event)
            indices = event.Indices;
            newData = event.NewData;
            currDepth = app.CurrentDepthEditField.Value;
            app.data.matrix{indices(1),indices(2),currDepth} = newData;
            app.MatrixTable.Data = app.data.matrix(:,:,currDepth);
        end

        % Close request function: Window
        function closeFcn(app, event)
            % Prompt user to save if there are unsaved changes
            if ~isequal(app.data,app.originalData)
                selection = uiconfirm(app.Window,'Save changes before closing?', 'Confirm Save',...
                    'Options',{'Save & Exit','Exit', 'Cancel'},...
                    'DefaultOption',1,'CancelOption',3);
                switch (selection)
                    case 'Save & Exit'
                        filename = app.NameEditField.Value;
                        saveData(app, filename);
                        delete(app.Window);
                    case 'Exit'
                        delete(app.Window);
                    case 'Cancel'
                        % Do nothing
                end
            else
                delete(app.Window);
            end
        end

        % Value changed function: ContinuousCheckBox
        function continuousChanged(app, event)
            toggleContinuous(app, app.ContinuousCheckBox.Value);
        end

        % Value changed function: FunctionEditField
        function functionChanged(app, event)
            app.data.func = event.Value;
        end

        % Button pushed function: NewButton
        function newFcn(app, event)
            % Prompt user to save any unsaved data
            if ~isequal(app.data,app.originalData)
                selection = uiconfirm(app.Window,'Save changes before opening new?', 'Confirm Save',...
                    'Options',{'Save','Don''t Save', 'Cancel'},...
                    'DefaultOption',1,'CancelOption',3);
                switch (selection)
                    case 'Save'
                        % Save stuff under specified filename
                        filename = app.NameEditField.Value;
                        saveData(app, filename);
                        % Reset to defaults
                        toggleDensity(app,false);
                        toggleContinuous(app,false);
                        app.FunctionEditField.Value = '';
                        defaultData(app);
                    case 'Don''t Save'
                        % Reset to defaults
                        toggleDensity(app,false);
                        toggleContinuous(app,false);
                        app.FunctionEditField.Value = '';
                        defaultData(app);
                    case 'Cancel'
                        % Do nothing
                end
            else
                % Reset to defaults
                toggleDensity(app,false);
                toggleContinuous(app,false);
                app.FunctionEditField.Value = '';
                defaultData(app);
            end
        end

        % Value changed function: DensityCheckBox
        function densityChanged(app, event)
            toggleDensity(app, app.DensityCheckBox.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Window = uifigure('Visible', 'off');
            app.Window.Name = 'Matrix Editor';
            app.Window.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.MatrixTable = uitable(app.Window);
            app.MatrixTable.CellEditCallback = createCallbackFcn(app, @valueChanged, true);
            app.RowsEditField = uieditfield(app.Window, 'numeric');
            app.RowsEditField.Limits = [1 Inf];
            app.RowsEditField.ValueChangedFcn = createCallbackFcn(app, @rowsChanged, true);
            app.RowsEditField.Value = 1;
            app.DimensionsLabel = uilabel(app.Window);
            app.DimensionsLabel.Text = 'Dimensions';
            app.ColumnsEditField = uieditfield(app.Window, 'numeric');
            app.ColumnsEditField.Limits = [1 Inf];
            app.ColumnsEditField.ValueChangedFcn = createCallbackFcn(app, @columnsChanged, true);
            app.ColumnsEditField.Value = 1;
            app.DepthEditField = uieditfield(app.Window, 'numeric');
            app.DepthEditField.Limits = [1 Inf];
            app.DepthEditField.ValueChangedFcn = createCallbackFcn(app, @depthChanged, true);
            app.DepthEditField.Value = 1;
            app.ViewLabel = uilabel(app.Window);
            app.ViewLabel.Text = 'View';
            app.CurrentDepthEditField = uieditfield(app.Window, 'numeric');
            app.CurrentDepthEditField.Limits = [1 Inf];
            app.CurrentDepthEditField.ValueChangedFcn = createCallbackFcn(app, @viewChanged, true);
            app.CurrentDepthEditField.Value = 1;
            app.FileLabel = uilabel(app.Window);
            app.FileLabel.Text = 'File';
            app.NameEditFieldLabel = uilabel(app.Window);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Window, 'text');
            app.NameEditField.Value = 'positions.mat';
            app.SaveButton = uibutton(app.Window, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonFcn, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.Window, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadFcn, true);
            app.LoadButton.Text = 'Load';
            app.MatrixTypeLabel = uilabel(app.Window);
            app.MatrixTypeLabel.Text = 'Matrix Type';
            app.ContinuousCheckBox = uicheckbox(app.Window);
            app.ContinuousCheckBox.ValueChangedFcn = createCallbackFcn(app, @continuousChanged, true);
            app.ContinuousCheckBox.Enable = 'off';
            app.ContinuousCheckBox.Text = 'Continuous?';
            app.FunctionEditField = uieditfield(app.Window, 'text');
            app.FunctionEditField.ValueChangedFcn = createCallbackFcn(app, @functionChanged, true);
            app.FunctionEditField.Editable = 'off';
            app.FunctionEditField.Enable = 'off';
            app.FunctionEditField.Tooltip = {'The continuous function in terms of x y t'};
            app.DepthLabel = uilabel(app.Window);
            app.DepthLabel.Text = 'Depth';
            app.CurrentDepthLabel = uilabel(app.Window);
            app.CurrentDepthLabel.Text = 'Current Depth';
            app.FunctionLabel = uilabel(app.Window);
            app.FunctionLabel.Enable = 'off';
            app.FunctionLabel.Text = 'Function';
            app.NewButton = uibutton(app.Window, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @newFcn, true);
            app.NewButton.Text = 'New';
            app.DensityCheckBox = uicheckbox(app.Window);
            app.DensityCheckBox.ValueChangedFcn = createCallbackFcn(app, @densityChanged, true);
            app.DensityCheckBox.Text = 'Density?';
            app.RowsLabel = uilabel(app.Window);
            app.RowsLabel.Text = 'Rows';
            app.ColumnsLabel = uilabel(app.Window);
            app.ColumnsLabel.Text = 'Columns';
            % Show the figure after all components are created
            app.Window.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatrixEditorLloyd

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Window)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Window)
        end
    end
end