classdef MatrixEditorOpinion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Window                 matlab.ui.Figure
        MatrixTable            matlab.ui.control.Table
        RowsEditField          matlab.ui.control.NumericEditField
        DimensionsLabel        matlab.ui.control.Label
        ColumnsEditField       matlab.ui.control.NumericEditField
        FileLabel              matlab.ui.control.Label
        NameEditFieldLabel     matlab.ui.control.Label
        NameEditField          matlab.ui.control.EditField
        SaveButton             matlab.ui.control.Button
        LoadButton             matlab.ui.control.Button
        ExtraInfoLabel         matlab.ui.control.Label
        NewButton              matlab.ui.control.Button
        RowsLabel              matlab.ui.control.Label
        ColumnsLabel           matlab.ui.control.Label
        DimensionsButtonGroup  matlab.ui.container.ButtonGroup
        One                    matlab.ui.control.RadioButton
        Two                    matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        data % data struct for matrix and/or func
        originalData % data struct for matrix and/or func upon last save
    end
    
    methods (Access = private)
        
        % Creates the default matrix, stores it in app.data, and displays it
        function defaultData(app)
            app.data.matrix = zeros(5,4);
            % Default to 1D
            app.data.isOneDimensional = true;
            toggleDimensionUI(app);
            app.originalData = app.data;
        end
        
        function saveData(app, filename)
            matrix = app.data.matrix;
            isOneDimensional = app.data.isOneDimensional;
            app.originalData = app.data;
            save(filename, 'matrix','isOneDimensional');
        end
        
        % Toggles UI elements based on dimension, including matrix column
        % names. Assumes app.data has already been updated
        function toggleDimensionUI(app)
            if app.data.isOneDimensional
                set(app.MatrixTable,'Data',app.data.matrix,'ColumnName',{...
                    'Radius of Comm.','Position','Left Noise','Right Noise'}');
                app.One.Value = true;
                app.Two.Value = false;
                app.RowsEditField.Value = size(app.data.matrix,1);
                app.ColumnsEditField.Value = 4;
            else
                set(app.MatrixTable,'Data',app.data.matrix,'ColumnName',{...
                    'Radius of Comm.','X Position','Y Position'});
                app.One.Value = false;
                app.Two.Value = true;
                app.RowsEditField.Value = size(app.data.matrix,1);
                app.ColumnsEditField.Value = 3;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Config
            set(app.MatrixTable,'ColumnEditable',true,'ColumnWidth','auto');
            
            filename = app.NameEditField.Value;
            % Check if there is an existing .mat file
            if isfile(filename)
                file = load(filename);
                try
                    % Load matrix entries
                    app.data.matrix = file.matrix;
                    % Load extra info
                    app.data.isOneDimensional = file.isOneDimensional;
                    app.originalData = app.data;
                    dimensions = size(app.data.matrix);
                    % Repopulate dimension fields
                    app.RowsEditField.Value = dimensions(1);
                    app.ColumnsEditField.Value = dimensions(2);
                    %% Display loaded data on UI
                    toggleDimensionUI(app);
                catch ME
                    disp(['Error: ' filename ' is corrupt. Please delete/fix and try again'])
                    disp(ME)
                    % Could use new blank data here, but that could
                    % overwrite any potentially recoverable data in the
                    % corrupt matrix
                    delete(app.Window);
                end
            else
                % Give user default data
                defaultData(app);
            end
        end

        % Button pushed function: LoadButton
        function loadFcn(app, event)
            filename = app.NameEditField.Value;
            % Check if there is an existing .mat file
            if isfile(filename)
                file = load(filename);
                try
                    % Load matrix entries
                    app.data.matrix = file.matrix;
                    % Load extra info
                    app.data.isOneDimensional = file.isOneDimensional;
                    dimensions = size(app.data.matrix);
                    % Repopulate dimension fields
                    app.RowsEditField.Value = dimensions(1);
                    app.ColumnsEditField.Value = dimensions(2);
                    %% Display loaded data on UI
                    toggleDimensionUI(app);
                catch ME
                    disp(['Error: ' filename ' is corrupt. Please delete/fix and try again'])
                    disp(ME)
                    % Could use new blank data here, but that could
                    % overwrite any potentially recoverable data in the
                    % corrupt matrix
                    delete(app.Window);
                end
            else
                disp(['Error: No file with name: ' filename])
            end
        end

        % Button pushed function: SaveButton
        function saveButtonFcn(app, event)
            filename = app.NameEditField.Value;
            if isfile(filename)
                selection = uiconfirm(app.Window,'File exists. Overwrite?', 'Confirm Save',...
                    'Options',{'Overwrite','Save as Copy', 'Cancel'},...
                    'DefaultOption',1,'CancelOption',3);
                switch (selection)
                    case 'Overwrite'
                        saveData(app, filename);
                    case 'Save as Copy'
                        fileParts = split(filename, '.');
                        filename = char(join([fileParts(1) '_copy.mat'],""));
                        saveData(app, filename);
                        % Also need to change the filename field
                        app.NameEditField.Value = filename;
                    case 'Cancel'
                        % Do nothing
                end
            else
                saveData(app, filename);
            end
        end

        % Value changed function: RowsEditField
        function rowsChanged(app, event)
            newNumRows = app.RowsEditField.Value;
            oldNumRows = size(app.data.matrix,1);
            deltaRows = newNumRows - oldNumRows;
            columns = size(app.data.matrix,2);
            % If we are deleting rows
            if deltaRows < 0
                app.data.matrix = app.data.matrix(1:newNumRows,:,:);
            % If we are adding rows
            elseif deltaRows > 0
                newRows = zeros(deltaRows, columns);
                app.data.matrix = cat(1, app.data.matrix, newRows);
            end
            app.MatrixTable.Data = app.data.matrix;
        end

        % Cell edit callback: MatrixTable
        function valueChanged(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.data.matrix(indices(1),indices(2)) = newData;
            app.MatrixTable.Data = app.data.matrix;
        end

        % Close request function: Window
        function closeFcn(app, event)
            % Prompt user to save if there are unsaved changes
            if ~isequal(app.data,app.originalData)
                selection = uiconfirm(app.Window,'Save changes before closing?', 'Confirm Save',...
                    'Options',{'Save & Exit','Exit', 'Cancel'},...
                    'DefaultOption',1,'CancelOption',3);
                switch (selection)
                    case 'Save & Exit'
                        filename = app.NameEditField.Value;
                        saveData(app, filename);
                        delete(app.Window);
                    case 'Exit'
                        delete(app.Window);
                    case 'Cancel'
                        % Do nothing
                end
            else
                delete(app.Window);
            end
        end

        % Button pushed function: NewButton
        function newFcn(app, event)
            % Prompt user to save any unsaved data
            if ~isequal(app.data,app.originalData)
                selection = uiconfirm(app.Window,'Save changes before opening new?', 'Confirm Save',...
                    'Options',{'Save','Don''t Save', 'Cancel'},...
                    'DefaultOption',1,'CancelOption',3);
                switch (selection)
                    case 'Save'
                        % Save stuff under specified filename
                        filename = app.NameEditField.Value;
                        saveData(app, filename);
                        % Reset to defaults
                        defaultData(app);
                    case 'Don''t Save'
                        % Reset to defaults
                        defaultData(app);
                    case 'Cancel'
                        % Do nothing
                end
            else
                % Reset to defaults
                defaultData(app);
            end
        end

        % Selection changed function: DimensionsButtonGroup
        function dimensionChanged(app, event)
            selection = uiconfirm(app.Window,'This could delete some data. Continue?', 'Confirm Change',...
                    'Options',{'Yes', 'Cancel'},...
                    'DefaultOption',1,'CancelOption',2);
            switch (selection)
                case 'Yes'
                    selectedButton = app.DimensionsButtonGroup.SelectedObject;
                    % If it was 2D and is now 1D
                    if strcmp(selectedButton.Text,'1')
                        % Keep rComm,xPos, add left/right noise columns
                        app.data.matrix = cat(2,app.data.matrix(:,1:2),zeros(size(app.data.matrix,1),2));
                        app.data.isOneDimensional = true;
                        toggleDimensionUI(app);
                    % If it was 1D and is now 2D
                    else
                        % Keep rComm,Pos, add y Pos
                        app.data.matrix = cat(2,app.data.matrix(:,1:2),zeros(size(app.data.matrix,1),1));
                        app.data.isOneDimensional = false;
                        toggleDimensionUI(app);
                    end
                case 'Cancel'
                    % Revert UI to previous state
                    toggleDimensionUI(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Window = uifigure('Visible', 'off');
            app.Window.Name = 'Matrix Editor';
            app.Window.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.MatrixTable = uitable(app.Window);
            app.MatrixTable.ColumnName = {'Radius of Comm.'; 'Position'; 'Left Noise'; 'Right Noise'};
            app.MatrixTable.CellEditCallback = createCallbackFcn(app, @valueChanged, true);
            app.RowsEditField = uieditfield(app.Window, 'numeric');
            app.RowsEditField.Limits = [1 Inf];
            app.RowsEditField.ValueChangedFcn = createCallbackFcn(app, @rowsChanged, true);
            app.RowsEditField.Value = 5;
            app.DimensionsLabel = uilabel(app.Window);
            app.DimensionsLabel.Text = 'Dimensions';
            app.ColumnsEditField = uieditfield(app.Window, 'numeric');
            app.ColumnsEditField.Limits = [1 Inf];
            app.ColumnsEditField.Editable = 'off';
            app.ColumnsEditField.Value = 4;
            app.FileLabel = uilabel(app.Window);
            app.FileLabel.Text = 'File';
            app.NameEditFieldLabel = uilabel(app.Window);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Window, 'text');
            app.NameEditField.Value = 'agentInfo.mat';
            app.SaveButton = uibutton(app.Window, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonFcn, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.Window, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadFcn, true);
            app.LoadButton.Text = 'Load';
            app.ExtraInfoLabel = uilabel(app.Window);
            app.ExtraInfoLabel.Text = 'Extra Info';
            app.NewButton = uibutton(app.Window, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @newFcn, true);
            app.NewButton.Text = 'New';
            app.RowsLabel = uilabel(app.Window);
            app.RowsLabel.Text = 'Rows';
            app.ColumnsLabel = uilabel(app.Window);
            app.ColumnsLabel.Text = 'Columns';
            app.DimensionsButtonGroup = uibuttongroup(app.Window);
            app.DimensionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @dimensionChanged, true);
            app.DimensionsButtonGroup.Title = 'Dimension(s)';
            app.One = uiradiobutton(app.DimensionsButtonGroup);
            app.One.Text = '1';
            app.One.Value = true;
            app.Two = uiradiobutton(app.DimensionsButtonGroup);
            app.Two.Text = '2';
            % Show the figure after all components are created
            app.Window.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatrixEditorOpinion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Window)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Window)
        end
    end
end