classdef appDesignerSolution < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        LoadDataButton          matlab.ui.control.Button
        StartSimulationButton   matlab.ui.control.Button
        StopSimulationButton    matlab.ui.control.Button
        FileNameEditFieldLabel  matlab.ui.control.Label
        FileNameEditField       matlab.ui.control.EditField
        UITable                 matlab.ui.control.Table
    end

    
    properties (Access = private)
        isStopPushed
        agentData
    end
    
    methods (Access = private)
        
        function plotArena(app)
            % plot the position of the agent with a square marker
            plot(app.UIAxes, app.agentData(1,1), app.agentData(1,2), 's');
            % plot point immediately
            drawnow;
        end
        
        function updateAgentData(app)
            % if the X position of the agent is at the boundaries of the arena (0 or 15), change the direction of the agent's X velocity
            if app.agentData(1,1) == 0 || app.agentData(1,1) == 15
                app.agentData(1,3) = -app.agentData(1,3);
            end
            % if the Y position of the agent is at the boundaries of the arena (0 or 9), change the direction of the agent's Y velocity
            if app.agentData(1,2) == 0 || app.agentData(1,2) == 9
                app.agentData(1,4) = -app.agentData(1,4);
            end
            % update the X position of the agent by adding the X velocity to the X position
            app.agentData(1,1) = app.agentData(1,1) + app.agentData(1,3);
            % update the Y position of the agent by adding the Y velocity to the Y position
            app.agentData(1,2) = app.agentData(1,2) + app.agentData(1,4);
            % update the data in the table
            app.UITable.Data = app.agentData;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            
            % save a variable called filename which is equal to the input from the File Name text box
            filename = app.FileNameEditField.Value;
            
            % load the data from filename
            file = load(filename);
            
            % update the data in the GUI table
            app.UITable.Data = file.agentData;
            
            % update the data in the property agentData
            app.agentData = file.agentData;
            
            % plot initial position of the agent
            set(app.UIAxes,'XLim',[0 15],'YLim',[0 9],'XTick',[0:15],'YTick',[0:9]);
            plotArena(app);
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            % set isStopPushed to false
            app.isStopPushed = false;
            
            % Simulation Loop %
            while ~app.isStopPushed   
                updateAgentData(app);
                plotArena(app);
            end
        end

        % Button pushed function: StopSimulationButton
        function StopSimulationButtonPushed(app, event)
            % set isStopPushed to true
            app.isStopPushed = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Arena')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.StartSimulationButton = uibutton(app.UIFigure, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.StopSimulationButton = uibutton(app.UIFigure, 'push');
            app.StopSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StopSimulationButtonPushed, true);
            app.StopSimulationButton.Text = 'Stop Simulation';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.Value = 'agentData.mat';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X Position'; 'Y Position'; 'X Velocity'; 'Y Velocity'};
            app.UITable.ColumnEditable = [true true true true];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appDesignerSolution

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end